classdef lavade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAVADEUIFigure                  matlab.ui.Figure
        DropDownRun                     matlab.ui.control.DropDown
        DropDownMeta                    matlab.ui.control.DropDown
        GroupingofDataCheckBox          matlab.ui.control.CheckBox
        PositionSingnalSlider           matlab.ui.control.Slider
        PositionofSignalLabel           matlab.ui.control.Label
        RightVEditField                 matlab.ui.control.NumericEditField
        RightVEditFieldLabel            matlab.ui.control.Label
        LeftVEditField                  matlab.ui.control.NumericEditField
        LeftVEditFieldLabel             matlab.ui.control.Label
        EndVEditField                   matlab.ui.control.NumericEditField
        EndVEditFieldLabel              matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        ENAlphaSlider                   matlab.ui.control.Slider
        ENAlphaSliderLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        HoldCheckBox                    matlab.ui.control.CheckBox
        StandardizeDataCheckBox         matlab.ui.control.CheckBox
        SigEEditField                   matlab.ui.control.NumericEditField
        SigEEditFieldLabel              matlab.ui.control.Label
        SigSEditField                   matlab.ui.control.NumericEditField
        SigSEditFieldLabel              matlab.ui.control.Label
        ShuffleSplitsButton             matlab.ui.control.Button
        RegularizationEditField         matlab.ui.control.NumericEditField
        RegularizationEditFieldLabel    matlab.ui.control.Label
        ComponentsEditField             matlab.ui.control.NumericEditField
        ComponentsEditFieldLabel        matlab.ui.control.Label
        AddNoiseCheckBox                matlab.ui.control.CheckBox
        LatentVariableMethodDemonstratorLabel  matlab.ui.control.Label
        DrawNewSampleButton             matlab.ui.control.Button
        SigREditField                   matlab.ui.control.NumericEditField
        SigREditFieldLabel              matlab.ui.control.Label
        SigLEditField                   matlab.ui.control.NumericEditField
        SigLEditFieldLabel              matlab.ui.control.Label
        SNRySlider                      matlab.ui.control.Slider
        SNRyLabel                       matlab.ui.control.Label
        StartVEditField                 matlab.ui.control.NumericEditField
        StartVEditFieldLabel            matlab.ui.control.Label
        ExperimentsEditField            matlab.ui.control.NumericEditField
        ExperimentsEditFieldLabel       matlab.ui.control.Label
        relDatapointsEditField          matlab.ui.control.NumericEditField
        relDatapointsEditFieldLabel     matlab.ui.control.Label
        DatapointsEditField             matlab.ui.control.NumericEditField
        DatapointsEditFieldLabel        matlab.ui.control.Label
        TrainTestSplitRatioSlider       matlab.ui.control.Slider
        TrainTestSplitRatioSliderLabel  matlab.ui.control.Label
        SNRXSlider                      matlab.ui.control.Slider
        SNRXLabel                       matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        UIAxesReg                       matlab.ui.control.UIAxes
        UIAxesRegTrain                  matlab.ui.control.UIAxes
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% LAVADE (Latent Variable Demonstrator Software)
% Developed with Matlab 2021a
% Author: Joachim Schaeffer
% Email:  joachim.schaeffer@posteo.de
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
    
    % Change access to public to be able to run case 
    % studies with an external script
    properties (Access = private)
        % Properties that can be accessed in all functions 
        % below, via app.VarName
        DataObj;    % custom object of class defined separately
        % Subplots in the panel section, these properties must 
        % be manually set up here for the subplots to work 
        % in the matlab GUI/App
        ax1;
        ax2;
        rank_text;       % Text for displaying the rank in ax1
    end
    
    % Change access to public to be able to run case 
    % studies with an external script
    methods (Access = private)
        
        function greek_letters(app)
        % Should run only when the app is started!
        % Cretae Panel Subplots 
            app.Panel.AutoResizeChildren = 'off';
            app.ax1 = subplot(2,1,1,'Parent',app.Panel);
            app.ax2 = subplot(2,1,2,'Parent',app.Panel);
            % Greek letters in text fields must be initialized here 
            app.SigLEditFieldLabel.Text = char(963);
            app.SigREditFieldLabel.Text = char(963);
            app.SigSEditFieldLabel.Text = char(963);
            app.SigEEditFieldLabel.Text = char(963);
            app.LeftVEditFieldLabel.Text = strcat("Left  ", char(956));
            app.RightVEditFieldLabel.Text = strcat("Right  ", char(956));
            app.StartVEditFieldLabel.Text = strcat("Start  ", char(956));
            app.EndVEditFieldLabel.Text = strcat("End  ", char(956));
            app.ENAlphaSliderLabel.Text = strcat("EN ", char(945));
        end
            
        function init(app)
        % Sets up the workspace and initializes variables
            addpath('./Data/') 
            addpath('./src/')
            % Initiate Data obj 
            app.DataObj = data_object_gui_interaction(app, app.DatasetDropDown.Value);
            noise(app);
        end 
        
        function noise(app)
            noise = app.AddNoiseCheckBox.Value;
            noise_X = app.SNRXSlider.Value;
            noise_y = app.SNRySlider.Value;
            app.DataObj.add_noise(noise_X, noise_y, noise);
        end

        function visibility_dataset(app)
        % Function to change visibility according to the 
        % selected dataset 
            app.TrainTestSplitRatioSlider.Limits = app.DataObj.ttr_limits;
            app.TrainTestSplitRatioSlider.MajorTicks = app.DataObj.ttr_majorticks;
   
            app.GroupingofDataCheckBox.Visible = app.DataObj.vis_cb; 
            app.SigSEditField.Visible = app.DataObj.vis;
            app.SigEEditField.Visible = app.DataObj.vis;
            app.SigLEditField.Visible = app.DataObj.vis;
            app.SigREditField.Visible = app.DataObj.vis;
            app.SigSEditFieldLabel.Visible = app.DataObj.vis;
            app.SigEEditFieldLabel.Visible = app.DataObj.vis;
            app.SigLEditFieldLabel.Visible = app.DataObj.vis;
            app.SigREditFieldLabel.Visible = app.DataObj.vis;
            
            app.StartVEditField.Visible = app.DataObj.vis;
            app.EndVEditField.Visible = app.DataObj.vis;
            app.LeftVEditField.Visible = app.DataObj.vis;
            app.RightVEditField.Visible = app.DataObj.vis;
            app.StartVEditFieldLabel.Visible = app.DataObj.vis;
            app.EndVEditFieldLabel.Visible = app.DataObj.vis;
            app.LeftVEditFieldLabel.Visible = app.DataObj.vis;
            app.RightVEditFieldLabel.Visible = app.DataObj.vis;
            
            app.PositionSingnalSlider.Visible = app.DataObj.vis;
            app.PositionofSignalLabel.Visible = app.DataObj.vis;
            
            app.ExperimentsEditField.Visible = app.DataObj.vis;
            app.relDatapointsEditField.Visible = app.DataObj.vis;
            app.DatapointsEditField.Visible = app.DataObj.vis;
            app.ExperimentsEditFieldLabel.Visible = app.DataObj.vis;
            app.relDatapointsEditFieldLabel.Visible = app.DataObj.vis;
            app.DatapointsEditFieldLabel.Visible = app.DataObj.vis;
            
            app.DrawNewSampleButton.Visible = app.DataObj.vis;
        end
        
        function visibility(app)
        % Function to change visibility according to the 
        % selected model 
            switch app.MethodDropDown.Value
                case 'PLS'
                    app.ComponentsEditField.Visible = 'on';
                    app.ComponentsEditFieldLabel.Visible = 'on';
                    app.RegularizationEditFieldLabel.Visible = 'off';
                    app.RegularizationEditField.Visible = 'off';
                    app.ENAlphaSlider.Visible = 'off';
                    app.ENAlphaSliderLabel.Visible = 'off';
                
                case 'PCR'
                    app.ComponentsEditField.Visible = 'on';
                    app.ComponentsEditFieldLabel.Visible = 'on';
                    app.RegularizationEditFieldLabel.Visible = 'off';
                    app.RegularizationEditField.Visible = 'off';
                    app.ENAlphaSlider.Visible = 'off';
                    app.ENAlphaSliderLabel.Visible = 'off';
  
                case 'RR'
                    app.RegularizationEditFieldLabel.Visible = 'on';
                    app.RegularizationEditField.Visible = 'on';
                    app.ComponentsEditField.Visible = 'off';
                    app.ComponentsEditFieldLabel.Visible = 'off';
                    app.ENAlphaSlider.Visible = 'off';
                    app.ENAlphaSliderLabel.Visible = 'off';
                
                case 'LASSO'
                    app.RegularizationEditFieldLabel.Visible = 'on';
                    app.RegularizationEditField.Visible = 'on';
                    app.ComponentsEditField.Visible = 'off';
                    app.ComponentsEditFieldLabel.Visible = 'off';
                    app.ENAlphaSlider.Visible = 'off';
                    app.ENAlphaSliderLabel.Visible = 'off';
                
                case 'EN'
                    app.RegularizationEditFieldLabel.Visible = 'on';
                    app.RegularizationEditField.Visible = 'on';
                    app.ComponentsEditField.Visible = 'off';
                    app.ComponentsEditFieldLabel.Visible = 'off';
                    app.ENAlphaSlider.Visible = 'on';
                    app.ENAlphaSliderLabel.Visible = 'on';
            end
        end
        
        function split_dataset(app, new)
        % Train Test Split
            group = app.GroupingofDataCheckBox.Value;
            fraction_test = app.TrainTestSplitRatioSlider.Value;            
            app.DataObj.train_test_split(fraction_test, new, group);
            % Make sure that number of components is feasible
            app.ComponentsEditField.Limits = [1 app.DataObj.u_lim];
        end
        
        function latent_variable_methods(app, plot_data)
        % This function is the core of the software tool and 
        % performs the (latent) variable regressions
        % PLS, CCA + Regression, PCA + Regression (PCR)
        % Ridge Regression (RR), LASSO and Elastic Net (EN)
        % Afterward it calls a function to plots the results

            r_xt = size(app.DataObj.X_test, 1);
            r_xtr = size(app.DataObj.X_train, 1);
            
            if app.StandardizeDataCheckBox.Value == true
                [X_train, mx, stdx] = zscore(app.DataObj.X_train);
                X_test = standardize_test(app.DataObj.X_test, mx, stdx);
            else
                X_train = app.DataObj.X_train;
                X_test = app.DataObj.X_test;
            end
            y_train = app.DataObj.y_train;
            y_test = app.DataObj.y_test;
            
            % Apply Latent Variable Methods
            % Canonical correlation analysis
            % Data is projected by performing cca on the training data.
            % Subsequently a least squares regression is performed on the
            % projected data
            visibility(app);
            
            switch app.MethodDropDown.Value
                case 'PLS'
                    ncomp = int64(app.ComponentsEditField.Value);
                    % [XL,YL,XS,YS,BETA,PCTVAR,MSE,stats] = plsregress(X_train, y_train, ncomp);
                    [~, ~, ~, ~, BETA, ~, ~, ~] = plsregress(X_train, y_train, ncomp);
                    coeff = BETA;
                    const = ones(r_xt, 1);
                    y_pred_test = [const, X_test]*BETA;
                    y_pred_train = [ones(r_xtr, 1), X_train]*BETA;
                
                case 'PCR'
                    % PCA + Regression: The data is first projected onto the
                    % #component proncipal component direction. Subsequently
                    % regression is performed 
                    [PCALoadings, PCAScores, ~] = pca(X_train,'Economy',false);
                    ncomp = int64(app.ComponentsEditField.Value);
                    betaPCR = regress(y_train-mean(y_train), PCAScores(:,1:ncomp));
                    betaPCR = PCALoadings(:,1:ncomp)*betaPCR;
                    betaPCR = [mean(y_train) - mean(X_train)*betaPCR; betaPCR];
                    coeff = betaPCR;
                    y_pred_test = [ones(r_xt,1) X_test]*betaPCR;
                    y_pred_train = [ones(r_xtr,1) X_train]*betaPCR;
            
                case 'RR'
                    % Ridge Regression, Least Square Regression with L2-norm
                    % penalty on the weights

                    k = app.RegularizationEditField.Value;
                    % RR has a standardization build in! The data is ALWAYS
                    % standardized --> Custom implementation for this tool.  
                    % B = ridge(y_train,X_train,k,0); %0 Scaled=0 restores coeff in org. space
                    % y_pred = B(1) + X_test*B(2:end);
                    % y_pred_train = B(1) + X_train*B(2:end);
                
                    B = ridge_regression(y_train,X_train,k);
                    y_pred_test = (X_test-mean(X_train))*B + mean(y_train);
                    y_pred_train = (X_train-mean(X_train))*B + mean(y_train);
                    coeff = B;
                         
                case 'LASSO'
                    % LASSO, Least Square Regression
                    k = app.RegularizationEditField.Value;
                    [B,FitInfo] = lasso(X_train, y_train, 'Lambda', k, 'Standardize',false);
                    y_pred_test = X_test*B + FitInfo.Intercept;
                    y_pred_train = X_train*B + FitInfo.Intercept;
                    coeff = B;
                
                case 'EN'
                    % Elastic Net, weighted combination of L1 & L2 Norm penalty on
                    % the weigts. 
                    k = app.RegularizationEditField.Value;
                    alpha = app.ENAlphaSlider.Value;
                    [B,FitInfo] = lasso(X_train, y_train, 'Lambda', k, 'Alpha', alpha, 'Standardize', false);
                    y_pred_test = X_test*B + FitInfo.Intercept;
                    y_pred_train = X_train*B + FitInfo.Intercept;
                    coeff = B;
            end
            if strcmp(app.DatasetDropDown.Value, 'LFP')
                y_train = 10.^y_train;
                y_test = 10.^y_test;
                y_pred_train = 10.^y_pred_train;
                y_pred_test = 10.^y_pred_test;
            end
                            
            rmse_train = calc_rmse(y_pred_train, y_train);
            rmse_test = calc_rmse(y_pred_test, y_test);
            % Save stats in a single variable
            stats = [rmse_train, rmse_test];
            
            if plot_data == true
                plot_figures(app, X_train, X_test, y_train, y_test, y_pred_train, y_pred_test, coeff, stats);
            end
            
        end
        
        function plot_figures(app, X_train, X_test, y_train, y_test, y_pred_train, y_pred_test, coeff, stats)
        % This function plots the data, regression coefficients
        % as well as the regression on the train and test data
            
            % Remove old plots
            cla(app.ax1)
            if app.HoldCheckBox.Value ~= true
                cla(app.ax2)
            end
            cla(app.UIAxesReg)
            cla(app.UIAxesRegTrain)

            % Plot the Data X  
            % Matlab warning: Let me know in case theres anything one can
            % do about it. The reason for this syntax is that ax1 and ax2 
            % shall be subplots in panel1 and thus the AppDesigner doesnt
            % recognize them as UIAxes, even though they are (Warning in
            % the editor, but no error when executing the code) 
            title(app.ax1, app.DatasetDropDown.Value)
            xlabel(app.ax1, app.DataObj.x_label_text)
            
            ylabel(app.ax1, app.DataObj.y_label_text)
            app.ax1.PlotBoxAspectRatio = [1.78515625 1 1];
            app.ax1.FontSize = 15;
            app.ax1.NextPlot = 'add';
            app.ax1.XGrid = 'on';
            app.ax1.YGrid = 'on';
            app.ax1.Box = 'on';
            app.ax1.XLim = [app.DataObj.x_values(1) app.DataObj.x_values(end)];
            [sxtr,~] = size(app.DataObj.X_train);
            [sxte,~] = size(app.DataObj.X_test);

            plot(app.ax1,app.DataObj.x_values, X_train(1,:), 'color', '#0072BD', 'DisplayName','Training');
            plot(app.ax1, app.DataObj.x_values, X_test(1,:),'color', '#D95319', 'DisplayName','Test');
            
            if strcmp(app.DatasetDropDown.Value, 'LFP')
                legend(app.ax1, 'Location', 'southeast', 'AutoUpdate','off');
            else
                legend(app.ax1, 'AutoUpdate','off');
            end
            
            for i=2:sxtr
                plot(app.ax1, app.DataObj.x_values, X_train(i,:), 'color', '#0072BD');
            end
            
            for i=2:sxte
               plot(app.ax1, app.DataObj.x_values, X_test(i,:),'color', '#D95319');
            end
            
            % Add text displaying rank fof the datamatrix
            if strcmp(app.DatasetDropDown.Value, 'LFP')
                y_pos = app.ax1.YLim(1)+0.075*(app.ax1.YLim(2)-app.ax1.YLim(1));
            else
                y_pos = app.ax1.YLim(2)-0.075*(app.ax1.YLim(2)-app.ax1.YLim(1));
            end
            
            rank_t = sprintf('Rank X: %.0f', rank(app.DataObj.X));
            app.rank_text = text(app.ax1, 0.03*(app.ax1.XLim(2)-app.ax1.XLim(1))+app.ax1.XLim(1), y_pos, rank_t, 'FontSize', 15);
            
            % Plot the regression coefficients
            title(app.ax2, 'Regression Coefficients')
            xlabel(app.ax2, app.DataObj.x_label_text)
            ylabel(app.ax2, '\beta', 'rotation',0,'VerticalAlignment','middle')
            app.ax2.PlotBoxAspectRatio = [1.78515625 1 1];
            app.ax2.FontSize = 15;
            app.ax2.NextPlot = 'add';
            app.ax2.XGrid = 'on';
            app.ax2.YGrid = 'on';
            app.ax2.Box = 'on';
            app.ax2.XLim = [app.DataObj.x_values(1) app.DataObj.x_values(end)];
            % Distinguish the case in which the model has a constant term
            if length(coeff) == size(app.DataObj.x_values, 2)
                % Model doesn't have a constant term
                plot(app.ax2, app.DataObj.x_values', coeff, "Color",'k');
                %t = sprintf('c = %.2f', 0);
                %y_pos = app.ax2.YLim(2)-0.1*(app.ax2.YLim(2)-app.ax2.YLim(1));
            else
                % Model has a constant term
                plot(app.ax2, app.DataObj.x_values', coeff(2:end), "Color",'k');
                %t = sprintf('c = %.2f', coeff(1));
                %y_pos = app.ax2.YLim(2)-0.1*(app.ax2.YLim(2)-app.ax2.YLim(1));
            end

            % Regression Plots
            markers = {...
                'o', '+', '*', '.', 'x', 's', 'd', '^', ... 
                'v', '>', '<', 'p', 'h' ...
                };
            

            % Train 
            title(app.UIAxesRegTrain, append(app.DataObj.output_text, ' of Training Data'))
            if exist(app.DataObj.group, 'var')
                if max(app.DataObj.group)
                    disp(['More than 13 groups detected, but only 13 appropriate markers available. ' ...
                        'Different groups will be displayed with the same marker']);
                end
                for i=1:numel(unique(app.DataObj.train_groups))
                    mask = sum(obj.group==obj.train_groups(i),2)==0;
                    scatter(app.UIAxesRegTrain, y_train, y_pred_train, markers{i}, 30, [0 0.4470 0.7410], 'filled');
                end
            else
                scatter(app.UIAxesRegTrain, y_train, y_pred_train, 30, [0 0.4470 0.7410], 'filled');
            end
            axis(app.UIAxesRegTrain, 'equal');
            xlim(app.UIAxesRegTrain, 'auto');
            ylim(app.UIAxesRegTrain, 'auto');
            app.UIAxesRegTrain.Box = 'on';
            text_train = sprintf('RMSE: %.2g', stats(1));
            
            % Test
            title(app.UIAxesReg, append(app.DataObj.output_text, ' of Test Data'))
            scatter(app.UIAxesReg, y_test, y_pred_test, 30, [0.8500 0.3250 0.0980], 'filled');
            axis(app.UIAxesReg, 'equal');
            xlim(app.UIAxesReg, 'auto');
            ylim(app.UIAxesReg, 'auto');
            app.UIAxesReg.Box = 'on';
            text_test = sprintf('RMSE: %.2g', stats(2));         
            
            % Manual linkage of axis, because linkaxis does not work with
            % UIAxis objects. 
            xlim_train = app.UIAxesRegTrain.XLim;
            xlim_test = app.UIAxesReg.XLim;
            ylim_train = app.UIAxesRegTrain.YLim;
            ylim_test = app.UIAxesReg.YLim;
            
            app.UIAxesRegTrain.XLim = [ ...
                min([xlim_test(1), xlim_train(1), ylim_test(1), ylim_train(1)]), ...
                max([xlim_test(2), xlim_train(2), ylim_test(2), ylim_train(2)]) ...
                ];
            app.UIAxesReg.XLim = app.UIAxesRegTrain.XLim;
            app.UIAxesRegTrain.YLim = app.UIAxesRegTrain.XLim;
            app.UIAxesReg.YLim = app.UIAxesRegTrain.XLim;
            
            line = linspace(app.UIAxesRegTrain.XLim(1), app.UIAxesRegTrain.XLim(2), 10);
            plot(app.UIAxesRegTrain, line, line, "Color",'k')
            plot(app.UIAxesReg, line, line, "Color",'k')
            
            y_pos = app.UIAxesRegTrain.YLim(2)-0.15*(app.UIAxesRegTrain.YLim(2)-app.UIAxesRegTrain.YLim(1));
            x_pos = app.UIAxesRegTrain.XLim(1)+0.08*(app.UIAxesRegTrain.XLim(2)-app.UIAxesRegTrain.XLim(1));
            text(app.UIAxesRegTrain, x_pos, y_pos, text_train, 'FontSize', 15);
            text(app.UIAxesReg, x_pos, y_pos, text_test, 'FontSize', 15);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Only called when starting the app 
            greek_letters(app);
            init(app);
            visibility_dataset(app);
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end

        % Value changed function: ComponentsEditField, ENAlphaSlider, 
        % MethodDropDown, RegularizationEditField, StandardizeDataCheckBox
        function UpdatePlotsButtonPushed2(app, event)
        % Runs the regression method and plots in case method 
        % or hyperparameters are changed    
            latent_variable_methods(app, 1);
        end

        % Callback function: DatapointsEditField, DrawNewSampleButton, 
        % EndVEditField, ExperimentsEditField, LeftVEditField, 
        % PositionSingnalSlider, RightVEditField, SigEEditField, 
        % SigLEditField, SigREditField, SigSEditField, StartVEditField, 
        % relDatapointsEditField
        function DrawNewSampleButtonPushed(app, event)
        % Draws new samples, only triggered in the Example case, 
        % otherwise values are invisible
            init(app);
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end

        % Value changed function: AddNoiseCheckBox, SNRXSlider, SNRySlider
        function SNRleftSliderValueChanged(app, event)
        % Triggers addition to noise 
            noise(app);
            split_dataset(app, 0);
            latent_variable_methods(app, 1);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
        % Adapts visivbility and inits the app in case the
        % dataset is changed    
            init(app); 
            visibility_dataset(app);
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end

        % Value changing function: TrainTestSplitRatioSlider
        function TrainTestChange(app, event)
        % Calls th efunction to change the train test ration, avoids
        % reloadign the dataset 
            split_dataset(app, 0);
            latent_variable_methods(app, 1);
        end

        % Callback function: GroupingofDataCheckBox, ShuffleSplitsButton
        function ShuffleSplitsButtonPushed(app, event)
        % Makes a new random split of the data without reloading
        % the dataset
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end

        % Value changed function: DropDownRun
        function DropDownRunValueChanged(app, event)
            init(app); 
            visibility_dataset(app);
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end

        % Value changed function: DropDownMeta
        function DropDownMetaValueChanged(app, event)
            init(app); 
            visibility_dataset(app);
            split_dataset(app, 1);
            latent_variable_methods(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAVADEUIFigure = uifigure('Visible', 'off');
            app.LAVADEUIFigure.Name = 'LAVADE';
            app.UIAxesRegTrain = uiaxes(app.LAVADEUIFigure);
            title(app.UIAxesRegTrain, 'Regression on Training Data')
            xlabel(app.UIAxesRegTrain, 'y_{true}')
            ylabel(app.UIAxesRegTrain, 'y_{pred}')
            app.UIAxesReg = uiaxes(app.LAVADEUIFigure);
            title(app.UIAxesReg, 'Regression on Test Data')
            xlabel(app.UIAxesReg, 'y_{true}')
            ylabel(app.UIAxesReg, 'y_{pred}')
            app.MethodDropDownLabel = uilabel(app.LAVADEUIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LAVADEUIFigure);
            app.MethodDropDown.Items = {'PLS', 'PCR', 'RR', 'LASSO', 'EN'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.MethodDropDown.Value = 'EN';
            app.SNRXLabel = uilabel(app.LAVADEUIFigure);
            app.SNRXLabel.Text = 'SNR: X';
            app.SNRXSlider = uislider(app.LAVADEUIFigure);
            app.SNRXSlider.Limits = [10 70];
            app.SNRXSlider.MajorTicks = [10 20 30 40 50 60 70];
            app.SNRXSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.SNRXSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 65];
            app.SNRXSlider.Value = 30;
            app.TrainTestSplitRatioSliderLabel = uilabel(app.LAVADEUIFigure);
            app.TrainTestSplitRatioSliderLabel.Text = 'Train-Test Split Ratio';
            app.TrainTestSplitRatioSlider = uislider(app.LAVADEUIFigure);
            app.TrainTestSplitRatioSlider.Limits = [0.1 0.9];
            app.TrainTestSplitRatioSlider.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.TrainTestSplitRatioSlider.ValueChangingFcn = createCallbackFcn(app, @TrainTestChange, true);
            app.TrainTestSplitRatioSlider.Value = 0.7;
            app.DatapointsEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.DatapointsEditFieldLabel.Text = '#Datapoints';
            app.DatapointsEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.DatapointsEditField.Limits = [10 10000];
            app.DatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DatapointsEditField.Value = 50;
            app.relDatapointsEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.relDatapointsEditFieldLabel.Text = '#rel. Datapoints';
            app.relDatapointsEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.relDatapointsEditField.Limits = [2 5000];
            app.relDatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.relDatapointsEditField.Value = 10;
            app.ExperimentsEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.ExperimentsEditFieldLabel.Text = '#Experiments';
            app.ExperimentsEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.ExperimentsEditField.Limits = [2 30000];
            app.ExperimentsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.ExperimentsEditField.Value = 30;
            app.StartVEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.StartVEditFieldLabel.Text = 'Start V';
            app.StartVEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.StartVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StartVEditField.Value = 2;
            app.SNRyLabel = uilabel(app.LAVADEUIFigure);
            app.SNRyLabel.Text = 'SNR: y';
            app.SNRySlider = uislider(app.LAVADEUIFigure);
            app.SNRySlider.Limits = [10 70];
            app.SNRySlider.MajorTicks = [1 10 20 30 40 50 60 70];
            app.SNRySlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.SNRySlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 65];
            app.SNRySlider.Value = 30;
            app.SigLEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.SigLEditFieldLabel.Text = 'SigL';
            app.SigLEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.SigLEditField.Limits = [0 Inf];
            app.SigLEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigLEditField.Value = 2;
            app.SigREditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.SigREditFieldLabel.Text = 'SigR';
            app.SigREditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.SigREditField.Limits = [0 Inf];
            app.SigREditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigREditField.Value = 2;
            app.DrawNewSampleButton = uibutton(app.LAVADEUIFigure, 'push');
            app.DrawNewSampleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DrawNewSampleButton.Text = 'Draw New Sample';
            app.LatentVariableMethodDemonstratorLabel = uilabel(app.LAVADEUIFigure);
            app.LatentVariableMethodDemonstratorLabel.Text = 'Latent Variable Method Demonstrator';
            app.AddNoiseCheckBox = uicheckbox(app.LAVADEUIFigure);
            app.AddNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.AddNoiseCheckBox.Text = 'Add Noise';
            app.ComponentsEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.ComponentsEditFieldLabel.Text = '#Components';
            app.ComponentsEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.ComponentsEditField.Limits = [1 100];
            app.ComponentsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.ComponentsEditField.Value = 1;
            app.RegularizationEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.RegularizationEditFieldLabel.Text = 'Regularization';
            app.RegularizationEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.RegularizationEditField.Limits = [0 Inf];
            app.RegularizationEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.RegularizationEditField.Value = 0.001;
            app.ShuffleSplitsButton = uibutton(app.LAVADEUIFigure, 'push');
            app.ShuffleSplitsButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleSplitsButtonPushed, true);
            app.ShuffleSplitsButton.Text = 'Shuffle Splits';
            app.SigSEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.SigSEditFieldLabel.Text = 'SigS';
            app.SigSEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.SigSEditField.Limits = [0 Inf];
            app.SigSEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigEEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.SigEEditFieldLabel.Text = 'SigE';
            app.SigEEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.SigEEditField.Limits = [0 Inf];
            app.SigEEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StandardizeDataCheckBox = uicheckbox(app.LAVADEUIFigure);
            app.StandardizeDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.StandardizeDataCheckBox.Text = {'Standardize Data'; ''};
            app.Panel = uipanel(app.LAVADEUIFigure);
            app.HoldCheckBox = uicheckbox(app.Panel);
            app.HoldCheckBox.Text = 'Hold';
            app.ENAlphaSliderLabel = uilabel(app.LAVADEUIFigure);
            app.ENAlphaSliderLabel.Text = 'EN Alpha';
            app.ENAlphaSlider = uislider(app.LAVADEUIFigure);
            app.ENAlphaSlider.Limits = [1e-07 1];
            app.ENAlphaSlider.MajorTicks = [0.05 0.25 0.5 0.75 1];
            app.ENAlphaSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.ENAlphaSlider.MinorTicks = [0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.ENAlphaSlider.Value = 0.5;
            app.DatasetDropDownLabel = uilabel(app.LAVADEUIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LAVADEUIFigure);
            app.DatasetDropDown.Items = {'Example', 'ATR-FTIR Spectra', 'Raman Spectra', 'LFP'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'Example';
            app.EndVEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.EndVEditFieldLabel.Text = 'End   V';
            app.EndVEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.EndVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.EndVEditField.Value = -5;
            app.LeftVEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.LeftVEditFieldLabel.Text = 'Left    V';
            app.LeftVEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.LeftVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.LeftVEditField.Value = -1;
            app.RightVEditFieldLabel = uilabel(app.LAVADEUIFigure);
            app.RightVEditFieldLabel.Text = 'Right  V';
            app.RightVEditField = uieditfield(app.LAVADEUIFigure, 'numeric');
            app.RightVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.RightVEditField.Value = -4;
            app.PositionofSignalLabel = uilabel(app.LAVADEUIFigure);
            app.PositionofSignalLabel.Text = 'Position of Signal';
            app.PositionSingnalSlider = uislider(app.LAVADEUIFigure);
            app.PositionSingnalSlider.Limits = [0 1];
            app.PositionSingnalSlider.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.PositionSingnalSlider.Value = 0.5;
            app.GroupingofDataCheckBox = uicheckbox(app.LAVADEUIFigure);
            app.GroupingofDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShuffleSplitsButtonPushed, true);
            app.GroupingofDataCheckBox.Text = 'Grouping of Data';
            app.GroupingofDataCheckBox.Value = true;
            app.DropDownMeta = uidropdown(app.LAVADEUIFigure);
            app.DropDownMeta.Items = {'Gluc', 'Lac', 'Gln', 'NH4'};
            app.DropDownMeta.ValueChangedFcn = createCallbackFcn(app, @DropDownMetaValueChanged, true);
            app.DropDownMeta.Value = 'Gluc';
            app.DropDownRun = uidropdown(app.LAVADEUIFigure);
            app.DropDownRun.Items = {'all', 'fb_r3', 'perf_r1', 'perf_r4', 'perf_R2', 'perf_R3'};
            app.DropDownRun.ValueChangedFcn = createCallbackFcn(app, @DropDownRunValueChanged, true);
            app.DropDownRun.Visible = 'off';
            app.DropDownRun.Value = 'all';
            % Show the figure after all components are created
            app.LAVADEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lavade

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAVADEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAVADEUIFigure)
        end
    end
end