classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxesFeature                 matlab.ui.control.UIAxes
        UIAxesCoeff                   matlab.ui.control.UIAxes
        UIAxesReg                     matlab.ui.control.UIAxes
        MethodDropDownLabel           matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        SNRleftSliderLabel            matlab.ui.control.Label
        SNRleftSlider                 matlab.ui.control.Slider
        TrainTestSplitSlider_3Label   matlab.ui.control.Label
        TrainTestSplitSlider          matlab.ui.control.Slider
        DatapointsEditFieldLabel      matlab.ui.control.Label
        DatapointsEditField           matlab.ui.control.NumericEditField
        relevantDatapointsEditFieldLabel  matlab.ui.control.Label
        relevantDatapointsEditField   matlab.ui.control.NumericEditField
        ExperimentsEditFieldLabel     matlab.ui.control.Label
        ExperimentsEditField          matlab.ui.control.NumericEditField
        StartValueEditFieldLabel      matlab.ui.control.Label
        StartValueEditField           matlab.ui.control.NumericEditField
        EndValueEditFieldLabel        matlab.ui.control.Label
        EndValueEditField             matlab.ui.control.NumericEditField
        signalSliderLabel             matlab.ui.control.Label
        signalSlider                  matlab.ui.control.Slider
        rightSliderLabel              matlab.ui.control.Label
        rightSlider                   matlab.ui.control.Slider
        ValueleftEditFieldLabel       matlab.ui.control.Label
        ValueleftEditField            matlab.ui.control.NumericEditField
        SigmaleftEditFieldLabel       matlab.ui.control.Label
        SigmaleftEditField            matlab.ui.control.NumericEditField
        ValuerightEditFieldLabel      matlab.ui.control.Label
        ValuerightEditField           matlab.ui.control.NumericEditField
        SigmarightEditFieldLabel      matlab.ui.control.Label
        SigmarightEditField           matlab.ui.control.NumericEditField
        DrawNewSampleButton           matlab.ui.control.Button
        LatentVariableMethodDemonstratorLabel  matlab.ui.control.Label
        PositionofSignalLabel         matlab.ui.control.Label
        PositionSingnalSlider         matlab.ui.control.Slider
        NoiseCheckBox                 matlab.ui.control.CheckBox
        ComponentsEditFieldLabel      matlab.ui.control.Label
        ComponentsEditField           matlab.ui.control.NumericEditField
        RegularizationEditFieldLabel  matlab.ui.control.Label
        RegularizationEditField       matlab.ui.control.NumericEditField
        UpdatePlotsButton             matlab.ui.control.Button
        UIAxesRegTrain                matlab.ui.control.UIAxes
        SigmaStartEditFieldLabel      matlab.ui.control.Label
        SigmaStartEditField           matlab.ui.control.NumericEditField
        SigmaEndEditFieldLabel        matlab.ui.control.Label
        SigmaEndEditField             matlab.ui.control.NumericEditField
        StandardizeInputsCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        X;
        X_train; 
        X_test; 
        y;
        y_train;
        y_test;
        rand_uc_l;
        rand_uc_r;
        noise_uc_l;
        noise_uc_r;
        signal;
        noise_signal;
        start_value;
        final_value;
        target_left;
        target_right;
        measurements;
    end
    
    methods (Access = private)
        
    function init(app)
            % formatting issues
            % app.UIAxes.SigmaleftEditFieldLabel.Interpreter = 'latex';
            % app.UIAxes.SigmaleftEditFieldLabel.Text = '$\sigma$';
            % text(app.SigmaleftEditFieldLabel, '$\sigma$');
            
            
            % Generate Examples
            app.measurements = app.ExperimentsEditField.Value;
            sigma_start = app.SigmaStartEditField.Value;
            app.start_value = normrnd(app.StartValueEditField.Value, sigma_start, app.measurements, 1);

            sigma_end = app.SigmaEndEditField.Value;
            app.final_value = normrnd(app.EndValueEditField.Value, sigma_end, app.measurements, 1);

            value_left = app.ValueleftEditField.Value;
            sigma_left = app.SigmaleftEditField.Value;
            
            value_right = app.ValuerightEditField.Value;
            sigma_right = app.SigmarightEditField.Value;
            
            app.target_left = normrnd(value_left, sigma_left, app.measurements, 1);
            app.target_right = normrnd(value_right, sigma_right, app.measurements, 1);
            
            data_points_signal = app.relevantDatapointsEditField.Value;
            
            % The y we want to predict: Slope of the signal section
            app.y = (app.target_right-app.target_left)/data_points_signal;
            
            create_signal(app);
        end
        
        function [y_pred, y_pred_train, coeff] = latent_variable_methods(app, X_train, X_test, y_train)
            [r_xt, col_xt] = size(X_test);
            [r_xtr, col_xtr] = size(X_train);
            %Apply Latent Variable Methods
            if strcmp(app.MethodDropDown.Value, 'CCA')
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                % [A,B,r,U,V,stats] = canoncorr(X_train, y_train);
                [A,B,~,~,~,~] = canoncorr(X_train, y_train);
                coeff = A/B;
                const_tr = ones(r_xtr, 1);
                X_train_ = [const_tr, X_train*coeff];
                b = regress(y_train-mean(y_train), X_train_); 
                
                const_t = ones(r_xt, 1);
                y_pred = [const_t, X_test*coeff]*b + mean(y_train);
                
                y_pred_train = X_train_*b + mean(y_train);
            elseif strcmp(app.MethodDropDown.Value, 'PLS')
                app.ComponentsEditField.Visible = 'on';
                app.ComponentsEditFieldLabel.Visible = 'on';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                ncomp = app.ComponentsEditField.Value;
                [XL,YL,XS,YS,BETA,PCTVAR,MSE,stats] = plsregress(X_train, y_train, ncomp);
                coeff = BETA;
                const = ones(r_xt, 1);
                y_pred = [const, X_test]*BETA;
                y_pred_train = [ones(r_xtr, 1), X_train]*BETA;
            elseif strcmp(app.MethodDropDown.Value, 'PCR')
                [PCALoadings, PCAScores, PCAVar] = pca(X_train,'Economy',false);
                app.ComponentsEditField.Visible = 'on';
                app.ComponentsEditFieldLabel.Visible = 'on';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                ncomp = int64(app.ComponentsEditField.Value);
                betaPCR = regress(y_train-mean(y_train), PCAScores(:,1:ncomp));
                betaPCR = PCALoadings(:,1:ncomp)*betaPCR;
                betaPCR = [mean(y_train) - mean(X_train)*betaPCR; betaPCR];
                coeff = betaPCR;
                y_pred = [ones(r_xt,1) X_test]*betaPCR;
                y_pred_train = [ones(r_xtr,1) X_train]*betaPCR;
            elseif strcmp(app.MethodDropDown.Value, 'RR')
                app.RegularizationEditFieldLabel.Visible = 'on';
                app.RegularizationEditField.Visible = 'on';
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                k = app.RegularizationEditField.Value;
                B = ridge(y_train,X_train,k,0); %0 Scaled=0 restores coeff in org. space
                coeff = B;
                y_pred = B(1) + X_test*B(2:end);
                y_pred_train = B(1) + X_train*B(2:end);
            elseif strcmp(app.MethodDropDown.Value, 'LASSO')
                app.RegularizationEditFieldLabel.Visible = 'on';
                app.RegularizationEditField.Visible = 'on';
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                k = app.RegularizationEditField.Value;
                [B,FitInfo] = lasso(X_train, y_train, 'Lambda', k);
                coeff = B;
                y_pred = X_test*B + FitInfo.Intercept;
                y_pred_train = X_train*B + FitInfo.Intercept;
            end
            
            
        end
        
        function plot(app, y_test, y_pred, y_pred_train, coeff)
            cla(app.UIAxesFeature)
            cla(app.UIAxesCoeff)
            cla(app.UIAxesReg)
            cla(app.UIAxesRegTrain)
            % Plot the stuff 
            [r,~] = size(app.X);
            for i=1:r
                plot(app.UIAxesFeature, app.X(i,:));
            end
           

            line = linspace(min(min(app.y_train), min(y_pred_train)), max(max(app.y_train), max(y_pred_train)), 10);
            plot(app.UIAxesRegTrain, line, line)
            scatter(app.UIAxesRegTrain, y_pred_train, app.y_train);
            
            line = linspace(min(min(y_test), min(y_pred)), max(max(y_test), max(y_pred)), 10);
            plot(app.UIAxesReg, line, line)
            scatter(app.UIAxesReg, y_pred, y_test);
            
            plot(app.UIAxesCoeff, coeff)
            
        end
        
        function create_signal(app)
            % create noisy uncorrelated data on the left and right of the
            % signal section             
            noise_l = app.SNRleftSlider.Value;
            noise_r = app.rightSlider.Value;          
            noise_s = app.signalSlider.Value;
            
            datapoints = app.DatapointsEditField.Value;
            data_points_signal = app.relevantDatapointsEditField.Value;
            data_points_left = int64(app.PositionSingnalSlider.Value*(datapoints-data_points_signal));     
            data_points_right = datapoints - (data_points_left+data_points_signal);
            
            app.rand_uc_l = zeros(app.measurements, data_points_left+1);
            app.rand_uc_r = zeros(app.measurements, data_points_right+1);
            app.noise_uc_l = zeros(app.measurements, data_points_left+1);
            app.noise_uc_r = zeros(app.measurements, data_points_right+1);
            app.signal = zeros(app.measurements, data_points_signal);
            app.noise_signal = zeros(app.measurements, data_points_signal);
            
            for i=1:app.measurements
                app.rand_uc_l(i, :) = linspace(app.start_value(i), app.target_left(i), data_points_left+1);
                app.noise_uc_l(i, :) = awgn(app.rand_uc_l(i, :), noise_l, 'measured');
                
                app.rand_uc_r(i, :) = linspace(app.target_right(i), app.final_value(i), data_points_right+1);
                app.noise_uc_r(i, :) = awgn(app.rand_uc_r(i, :), noise_r, 'measured');

                app.signal(i, :) =  linspace(app.target_left(i), app.target_right(i), data_points_signal);
                app.noise_signal(i, :) = awgn(app.signal(i, :), noise_s, 'measured');
            end
            
            if app.NoiseCheckBox.Value == true
                app.X = [app.noise_uc_l(:, 1:end-1), app.noise_signal, app.noise_uc_r(:, 2:end)];
            else
                app.X = [app.rand_uc_l(:, 1:end-1), app.signal, app.rand_uc_r(:, 2:end)];
            end
       
            % Train Test Split
            fraction_test = app.TrainTestSplitSlider.Value;            
            split_ind = int64(fraction_test*app.measurements);
            
            app.X_train = app.X(1:split_ind, :);
            app.X_test = app.X(split_ind+1:end, :);

            app.y_train = app.y(1:split_ind, :);
            app.y_test = app.y(split_ind+1:end, :);
            
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            init(app);
        end

        % Callback function: ComponentsEditField, MethodDropDown, 
        % RegularizationEditField, UpdatePlotsButton
        function UpdatePlotsButtonPushed2(app, event)
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
        end

        % Callback function: DatapointsEditField, 
        % DrawNewSampleButton, EndValueEditField, 
        % ExperimentsEditField, SigmaEndEditField, 
        % SigmaStartEditField, SigmaleftEditField, 
        % SigmarightEditField, StartValueEditField, 
        % ValueleftEditField, ValuerightEditField, 
        % relevantDatapointsEditField
        function DrawNewSampleButtonPushed(app, event)
            init(app)
        end

        % Value changed function: NoiseCheckBox, 
        % PositionSingnalSlider, SNRleftSlider, 
        % TrainTestSplitSlider, rightSlider, signalSlider
        function SNRleftSliderValueChanged(app, event)
            create_signal(app); 
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesFeature = uiaxes(app.UIFigure);
            title(app.UIAxesFeature, 'Features')
            xlabel(app.UIAxesFeature, 'idx')
            ylabel(app.UIAxesFeature, 'X')
            app.UIAxesCoeff = uiaxes(app.UIFigure);
            title(app.UIAxesCoeff, 'Regression Coefficients')
            xlabel(app.UIAxesCoeff, 'X')
            ylabel(app.UIAxesCoeff, 'Coeff')
            app.UIAxesReg = uiaxes(app.UIFigure);
            title(app.UIAxesReg, 'Regression on Test Data')
            xlabel(app.UIAxesReg, 'y_{pred}')
            ylabel(app.UIAxesReg, 'y_{true}')
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'PLS', 'CCA', 'PCR', 'RR', 'LASSO'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.MethodDropDown.Value = 'CCA';
            app.SNRleftSliderLabel = uilabel(app.UIFigure);
            app.SNRleftSliderLabel.Text = 'SNR left';
            app.SNRleftSlider = uislider(app.UIFigure);
            app.SNRleftSlider.Limits = [1 50];
            app.SNRleftSlider.MajorTicks = [1 10 20 30 40 50];
            app.SNRleftSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.SNRleftSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.SNRleftSlider.Value = 30;
            app.TrainTestSplitSlider_3Label = uilabel(app.UIFigure);
            app.TrainTestSplitSlider_3Label.Text = 'Train-Test Split';
            app.TrainTestSplitSlider = uislider(app.UIFigure);
            app.TrainTestSplitSlider.Limits = [0.1 0.9];
            app.TrainTestSplitSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.TrainTestSplitSlider.Value = 0.7;
            app.DatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.DatapointsEditFieldLabel.Text = '#Datapoints';
            app.DatapointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatapointsEditField.Limits = [10 10000];
            app.DatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DatapointsEditField.Value = 50;
            app.relevantDatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.relevantDatapointsEditFieldLabel.Text = '#relevant Datapoints';
            app.relevantDatapointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.relevantDatapointsEditField.Limits = [2 5000];
            app.relevantDatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.relevantDatapointsEditField.Value = 10;
            app.ExperimentsEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentsEditFieldLabel.Text = '#Experiments';
            app.ExperimentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExperimentsEditField.Limits = [2 10000];
            app.ExperimentsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.ExperimentsEditField.Value = 30;
            app.StartValueEditFieldLabel = uilabel(app.UIFigure);
            app.StartValueEditFieldLabel.Text = 'Start Value';
            app.StartValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartValueEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StartValueEditField.Value = 2;
            app.EndValueEditFieldLabel = uilabel(app.UIFigure);
            app.EndValueEditFieldLabel.Text = 'End Value';
            app.EndValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndValueEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.EndValueEditField.Value = -5;
            app.signalSliderLabel = uilabel(app.UIFigure);
            app.signalSliderLabel.Text = 'signal';
            app.signalSlider = uislider(app.UIFigure);
            app.signalSlider.Limits = [1 50];
            app.signalSlider.MajorTicks = [1 10 20 30 40 50];
            app.signalSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.signalSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.signalSlider.Value = 30;
            app.rightSliderLabel = uilabel(app.UIFigure);
            app.rightSliderLabel.Text = 'right';
            app.rightSlider = uislider(app.UIFigure);
            app.rightSlider.Limits = [1 50];
            app.rightSlider.MajorTicks = [1 10 20 30 40 50];
            app.rightSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.rightSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.rightSlider.Value = 30;
            app.ValueleftEditFieldLabel = uilabel(app.UIFigure);
            app.ValueleftEditFieldLabel.Text = ' Value left';
            app.ValueleftEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueleftEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.ValueleftEditField.Value = -1;
            app.SigmaleftEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaleftEditFieldLabel.Text = 'Sigma left';
            app.SigmaleftEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaleftEditField.Limits = [0 Inf];
            app.SigmaleftEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigmaleftEditField.Value = 2;
            app.ValuerightEditFieldLabel = uilabel(app.UIFigure);
            app.ValuerightEditFieldLabel.Text = ' Value right';
            app.ValuerightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValuerightEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.ValuerightEditField.Value = -4;
            app.SigmarightEditFieldLabel = uilabel(app.UIFigure);
            app.SigmarightEditFieldLabel.Text = 'Sigma right';
            app.SigmarightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmarightEditField.Limits = [0 Inf];
            app.SigmarightEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigmarightEditField.Value = 2;
            app.DrawNewSampleButton = uibutton(app.UIFigure, 'push');
            app.DrawNewSampleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DrawNewSampleButton.Text = 'Draw New Sample';
            app.LatentVariableMethodDemonstratorLabel = uilabel(app.UIFigure);
            app.LatentVariableMethodDemonstratorLabel.Text = 'Latent Variable Method Demonstrator';
            app.PositionofSignalLabel = uilabel(app.UIFigure);
            app.PositionofSignalLabel.Text = 'Position of Signal';
            app.PositionSingnalSlider = uislider(app.UIFigure);
            app.PositionSingnalSlider.Limits = [0 1];
            app.PositionSingnalSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.PositionSingnalSlider.Value = 0.5;
            app.NoiseCheckBox = uicheckbox(app.UIFigure);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.ComponentsEditFieldLabel = uilabel(app.UIFigure);
            app.ComponentsEditFieldLabel.Visible = 'off';
            app.ComponentsEditFieldLabel.Text = '#Components';
            app.ComponentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComponentsEditField.Limits = [1 10];
            app.ComponentsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.ComponentsEditField.Visible = 'off';
            app.ComponentsEditField.Value = 1;
            app.RegularizationEditFieldLabel = uilabel(app.UIFigure);
            app.RegularizationEditFieldLabel.Visible = 'off';
            app.RegularizationEditFieldLabel.Text = 'Regularization';
            app.RegularizationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegularizationEditField.Limits = [0 Inf];
            app.RegularizationEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.RegularizationEditField.Visible = 'off';
            app.RegularizationEditField.Value = 0.001;
            app.UpdatePlotsButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.UpdatePlotsButton.Text = {'Update Plots'; ''};
            app.UIAxesRegTrain = uiaxes(app.UIFigure);
            title(app.UIAxesRegTrain, 'Regression on Train Data')
            xlabel(app.UIAxesRegTrain, 'y_{pred}')
            ylabel(app.UIAxesRegTrain, 'y_{true}')
            app.SigmaStartEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaStartEditFieldLabel.Text = 'Sigma Start';
            app.SigmaStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaStartEditField.Limits = [0 Inf];
            app.SigmaStartEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigmaEndEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEndEditFieldLabel.Text = 'Sigma End';
            app.SigmaEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEndEditField.Limits = [0 Inf];
            app.SigmaEndEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StandardizeInputsCheckBox = uicheckbox(app.UIFigure);
            app.StandardizeInputsCheckBox.Text = {'Standardize Inputs'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end