classdef lavade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxesFeature                 matlab.ui.control.UIAxes
        UIAxesCoeff                   matlab.ui.control.UIAxes
        UIAxesReg                     matlab.ui.control.UIAxes
        MethodDropDownLabel           matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        SNRLeftSliderLabel            matlab.ui.control.Label
        SNRLeftSlider                 matlab.ui.control.Slider
        TrainTestSplitSlider_3Label   matlab.ui.control.Label
        TrainTestSplitSlider          matlab.ui.control.Slider
        DatapointsEditFieldLabel      matlab.ui.control.Label
        DatapointsEditField           matlab.ui.control.NumericEditField
        relevantDatapointsEditFieldLabel  matlab.ui.control.Label
        relevantDatapointsEditField   matlab.ui.control.NumericEditField
        ExperimentsEditFieldLabel     matlab.ui.control.Label
        ExperimentsEditField          matlab.ui.control.NumericEditField
        StartVEditFieldLabel          matlab.ui.control.Label
        StartVEditField               matlab.ui.control.NumericEditField
        EndVLabel                     matlab.ui.control.Label
        EndVEditField                 matlab.ui.control.NumericEditField
        SignalSliderLabel             matlab.ui.control.Label
        SignalSlider                  matlab.ui.control.Slider
        RightSliderLabel              matlab.ui.control.Label
        RightSlider                   matlab.ui.control.Slider
        LeftVLabel                    matlab.ui.control.Label
        LeftVEditField                matlab.ui.control.NumericEditField
        SigLEditFieldLabel            matlab.ui.control.Label
        SigLEditField                 matlab.ui.control.NumericEditField
        RightVLabel                   matlab.ui.control.Label
        RightVEditField               matlab.ui.control.NumericEditField
        SigREditFieldLabel            matlab.ui.control.Label
        SigREditField                 matlab.ui.control.NumericEditField
        DrawNewSampleButton           matlab.ui.control.Button
        LatentVariableMethodDemonstratorLabel  matlab.ui.control.Label
        PositionofSignalLabel         matlab.ui.control.Label
        PositionSingnalSlider         matlab.ui.control.Slider
        NoiseCheckBox                 matlab.ui.control.CheckBox
        ComponentsEditFieldLabel      matlab.ui.control.Label
        ComponentsEditField           matlab.ui.control.NumericEditField
        RegularizationEditFieldLabel  matlab.ui.control.Label
        RegularizationEditField       matlab.ui.control.NumericEditField
        UpdatePlotsButton             matlab.ui.control.Button
        UIAxesRegTrain                matlab.ui.control.UIAxes
        SigSEditFieldLabel            matlab.ui.control.Label
        SigSEditField                 matlab.ui.control.NumericEditField
        SigEEditFieldLabel            matlab.ui.control.Label
        SigEEditField                 matlab.ui.control.NumericEditField
        StandardizeInputsCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        X;
        X_train; 
        X_test; 
        y;
        y_train;
        y_test;
        rand_uc_l;
        rand_uc_r;
        noise_uc_l;
        noise_uc_r;
        signal;
        noise_signal;
        start_value;
        final_value;
        target_left;
        target_right;
        measurements;
    end
    
    methods (Access = private)
        
    function init(app)
            % formatting issues
            % app.SigLEditFieldLabel.Interpreter = 'latex';
            % app.SigLEditFieldLabel.Text = '$\sigma$';
            app.SigLEditFieldLabel.Text = char(963);
            app.SigREditFieldLabel.Text = char(963);
            app.SigSEditFieldLabel.Text = char(963);
            app.SigEEditFieldLabel.Text = char(963);
            
            app.LeftVLabel.Text = strcat("Left  ", char(956));
            app.RightVLabel.Text = strcat("Right  ", char(956));
            app.StartVEditFieldLabel.Text = strcat("Start  ", char(956));
            app.EndVLabel.Text = strcat("End  ", char(956));


            % Generate Examples
            app.measurements = app.ExperimentsEditField.Value;
            sigma_start = app.SigSEditField.Value;
            app.start_value = normrnd(app.StartVEditField.Value, sigma_start, app.measurements, 1);

            sigma_end = app.SigEEditField.Value;
            app.final_value = normrnd(app.EndVEditField.Value, sigma_end, app.measurements, 1);

            value_left = app.LeftVEditField.Value;
            sigma_left = app.SigLEditField.Value;
            
            value_right = app.RightVEditField.Value;
            sigma_right = app.SigREditField.Value;
            
            app.target_left = normrnd(value_left, sigma_left, app.measurements, 1);
            app.target_right = normrnd(value_right, sigma_right, app.measurements, 1);
            
            data_points_signal = app.relevantDatapointsEditField.Value;
            
            % The y we want to predict: Slope of the signal section
            app.y = (app.target_right-app.target_left)/data_points_signal;
            
            create_signal(app);
        end
        
        function [y_pred, y_pred_train, coeff] = latent_variable_methods(app, X_train, X_test, y_train)
            [r_xt, col_xt] = size(X_test);
            [r_xtr, col_xtr] = size(X_train);
            %Apply Latent Variable Methods
            if strcmp(app.MethodDropDown.Value, 'CCA')
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                % [A,B,r,U,V,stats] = canoncorr(X_train, y_train);
                [A,B,~,~,~,~] = canoncorr(X_train, y_train);
                coeff = A/B;
                const_tr = ones(r_xtr, 1);
                X_train_ = [const_tr, X_train*coeff];
                b = regress(y_train-mean(y_train), X_train_); 
                
                const_t = ones(r_xt, 1);
                y_pred = [const_t, X_test*coeff]*b + mean(y_train);
                
                y_pred_train = X_train_*b + mean(y_train);
            elseif strcmp(app.MethodDropDown.Value, 'PLS')
                app.ComponentsEditField.Visible = 'on';
                app.ComponentsEditFieldLabel.Visible = 'on';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                ncomp = app.ComponentsEditField.Value;
                [XL,YL,XS,YS,BETA,PCTVAR,MSE,stats] = plsregress(X_train, y_train, ncomp);
                coeff = BETA;
                const = ones(r_xt, 1);
                y_pred = [const, X_test]*BETA;
                y_pred_train = [ones(r_xtr, 1), X_train]*BETA;
            elseif strcmp(app.MethodDropDown.Value, 'PCR')
                [PCALoadings, PCAScores, PCAVar] = pca(X_train,'Economy',false);
                app.ComponentsEditField.Visible = 'on';
                app.ComponentsEditFieldLabel.Visible = 'on';
                app.RegularizationEditFieldLabel.Visible = 'off';
                app.RegularizationEditField.Visible = 'off';
                ncomp = int64(app.ComponentsEditField.Value);
                betaPCR = regress(y_train-mean(y_train), PCAScores(:,1:ncomp));
                betaPCR = PCALoadings(:,1:ncomp)*betaPCR;
                betaPCR = [mean(y_train) - mean(X_train)*betaPCR; betaPCR];
                coeff = betaPCR;
                y_pred = [ones(r_xt,1) X_test]*betaPCR;
                y_pred_train = [ones(r_xtr,1) X_train]*betaPCR;
            elseif strcmp(app.MethodDropDown.Value, 'RR')
                app.RegularizationEditFieldLabel.Visible = 'on';
                app.RegularizationEditField.Visible = 'on';
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                k = app.RegularizationEditField.Value;
                B = ridge(y_train,X_train,k,0); %0 Scaled=0 restores coeff in org. space
                coeff = B;
                y_pred = B(1) + X_test*B(2:end);
                y_pred_train = B(1) + X_train*B(2:end);
            elseif strcmp(app.MethodDropDown.Value, 'LASSO')
                app.RegularizationEditFieldLabel.Visible = 'on';
                app.RegularizationEditField.Visible = 'on';
                app.ComponentsEditField.Visible = 'off';
                app.ComponentsEditFieldLabel.Visible = 'off';
                k = app.RegularizationEditField.Value;
                [B,FitInfo] = lasso(X_train, y_train, 'Lambda', k);
                coeff = B;
                y_pred = X_test*B + FitInfo.Intercept;
                y_pred_train = X_train*B + FitInfo.Intercept;
            end
            
            
        end
        
        function plot_trial(app, y_test, y_pred, y_pred_train, coeff)
            cla(app.UIAxesFeature)
            cla(app.UIAxesCoeff)
            cla(app.UIAxesReg)
            cla(app.UIAxesRegTrain)
            % Plot the stuff 
            
            
            app.UIFigure.AutoResizeChildren = 'off';
            ax1 = subplot(1,2,1,'Parent',app.UIFigure);
            ax2 = subplot(1,2,2,'Parent',app.UIFigure);
                    
            title(ax1, 'Features')
            xlabel(ax1, 'idx')
            ylabel(ax1, 'X')
            ax1.PlotBoxAspectRatio = [1.78515625 1 1];
            ax1.FontSize = 15;
            ax1.NextPlot = 'add';
            ax1.XGrid = 'on';
            ax1.YGrid = 'on';
            % ax1.BackgroundColor = [1 1 1];
            ax1.Position = [50 385 510 315];
            
            [r,~] = size(app.X);
            for i=1:r
                plot(ax1, app.X(i,:));
            end
                % plot(ax1,[1 2 3 4])
                % plot(ax2,[10 9 4 7])


            if length(coeff) == 50
                % plot(app.UIAxesCoeff, coeff);
                t = sprintf('c = %.2f', 0);
                % title(legend(app.UIAxesCoeff), t)
                % y_pos = app.UIAxesCoeff.YLim(2)-0.1*(app.UIAxesCoeff.YLim(2)-app.UIAxesCoeff.YLim(1));
                % text(app.UIAxesCoeff, 2, y_pos, t, 'FontSize', 15);
                
            else
                plot(app.UIAxesCoeff, coeff(2:end));
                t = sprintf('c = %.2f', coeff(1));
                % title(legend(app.UIAxesCoeff), t)
                y_pos = app.UIAxesCoeff.YLim(2)-0.1*(app.UIAxesCoeff.YLim(2)-app.UIAxesCoeff.YLim(1));
                text(app.UIAxesCoeff, 2, y_pos, t, 'FontSize', 15);
            end
            
            line = linspace(min(min(app.y_train), min(y_pred_train)), max(max(app.y_train), max(y_pred_train)), 10);
            plot(app.UIAxesRegTrain, line, line)
            scatter(app.UIAxesRegTrain, y_pred_train, app.y_train);
            
            line = linspace(min(min(y_test), min(y_pred)), max(max(y_test), max(y_pred)), 10);
            plot(app.UIAxesReg, line, line)
            scatter(app.UIAxesReg, y_pred, y_test);
            
        end
        
        
        function plot(app, y_test, y_pred, y_pred_train, coeff)
            cla(app.UIAxesFeature)
            cla(app.UIAxesCoeff)
            cla(app.UIAxesReg)
            cla(app.UIAxesRegTrain)
            % Plot the stuff 
            [r,~] = size(app.X);
            for i=1:r
                plot(app.UIAxesFeature, app.X(i,:));
            end
           

            line = linspace(min(min(app.y_train), min(y_pred_train)), max(max(app.y_train), max(y_pred_train)), 10);
            plot(app.UIAxesRegTrain, line, line)
            scatter(app.UIAxesRegTrain, y_pred_train, app.y_train);
            
            line = linspace(min(min(y_test), min(y_pred)), max(max(y_test), max(y_pred)), 10);
            plot(app.UIAxesReg, line, line)
            scatter(app.UIAxesReg, y_pred, y_test);
            
            if length(coeff) == 50
                plot(app.UIAxesCoeff, coeff);
                t = sprintf('c = %.2f', 0);
                %title(legend(app.UIAxesCoeff), t)
                y_pos = app.UIAxesCoeff.YLim(2)-0.1*(app.UIAxesCoeff.YLim(2)-app.UIAxesCoeff.YLim(1));
                text(app.UIAxesCoeff, 2, y_pos, t, 'FontSize', 15);
                
            else
                plot(app.UIAxesCoeff, coeff(2:end));
                t = sprintf('c = %.2f', coeff(1));
                % title(legend(app.UIAxesCoeff), t)
                y_pos = app.UIAxesCoeff.YLim(2)-0.1*(app.UIAxesCoeff.YLim(2)-app.UIAxesCoeff.YLim(1));
                text(app.UIAxesCoeff, 2, y_pos, t, 'FontSize', 15);
            end
            
        end
        
        function create_signal(app)
            % create noisy uncorrelated data on the left and right of the
            % signal section             
            noise_l = app.SNRLeftSlider.Value;
            noise_r = app.RightSlider.Value;          
            noise_s = app.SignalSlider.Value;
            
            datapoints = app.DatapointsEditField.Value;
            data_points_signal = app.relevantDatapointsEditField.Value;
            data_points_left = int64(app.PositionSingnalSlider.Value*(datapoints-data_points_signal));     
            data_points_right = datapoints - (data_points_left+data_points_signal);
            
            app.rand_uc_l = zeros(app.measurements, data_points_left+1);
            app.rand_uc_r = zeros(app.measurements, data_points_right+1);
            app.noise_uc_l = zeros(app.measurements, data_points_left+1);
            app.noise_uc_r = zeros(app.measurements, data_points_right+1);
            app.signal = zeros(app.measurements, data_points_signal);
            app.noise_signal = zeros(app.measurements, data_points_signal);
            
            for i=1:app.measurements
                app.rand_uc_l(i, :) = linspace(app.start_value(i), app.target_left(i), data_points_left+1);
                app.noise_uc_l(i, :) = awgn(app.rand_uc_l(i, :), noise_l, 'measured');
                
                app.rand_uc_r(i, :) = linspace(app.target_right(i), app.final_value(i), data_points_right+1);
                app.noise_uc_r(i, :) = awgn(app.rand_uc_r(i, :), noise_r, 'measured');

                app.signal(i, :) =  linspace(app.target_left(i), app.target_right(i), data_points_signal);
                app.noise_signal(i, :) = awgn(app.signal(i, :), noise_s, 'measured');
            end
            
            if app.NoiseCheckBox.Value == true
                app.X = [app.noise_uc_l(:, 1:end-1), app.noise_signal, app.noise_uc_r(:, 2:end)];
            else
                app.X = [app.rand_uc_l(:, 1:end-1), app.signal, app.rand_uc_r(:, 2:end)];
            end
       
            % Train Test Split
            fraction_test = app.TrainTestSplitSlider.Value;            
            split_ind = int64(fraction_test*app.measurements);
            
            app.X_train = app.X(1:split_ind, :);
            app.X_test = app.X(split_ind+1:end, :);

            app.y_train = app.y(1:split_ind, :);
            app.y_test = app.y(split_ind+1:end, :);
            
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            init(app);
        end

        % Callback function: ComponentsEditField, MethodDropDown, 
        % RegularizationEditField, UpdatePlotsButton
        function UpdatePlotsButtonPushed2(app, event)
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
        end

        % Callback function: DatapointsEditField, 
        % DrawNewSampleButton, EndVEditField, ExperimentsEditField, 
        % LeftVEditField, RightVEditField, SigEEditField, 
        % SigLEditField, SigREditField, SigSEditField, 
        % StartVEditField, relevantDatapointsEditField
        function DrawNewSampleButtonPushed(app, event)
            init(app)
        end

        % Value changed function: NoiseCheckBox, 
        % PositionSingnalSlider, RightSlider, SNRLeftSlider, 
        % SignalSlider, TrainTestSplitSlider
        function SNRleftSliderValueChanged(app, event)
            create_signal(app); 
            [y_pred, y_pred_train, coeff] = latent_variable_methods(app, app.X_train, app.X_test, app.y_train);
            plot(app, app.y_test, y_pred, y_pred_train, coeff);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesFeature = uiaxes(app.UIFigure);
            title(app.UIAxesFeature, 'Features')
            xlabel(app.UIAxesFeature, 'idx')
            ylabel(app.UIAxesFeature, 'X')
            app.UIAxesCoeff = uiaxes(app.UIFigure);
            title(app.UIAxesCoeff, 'Regression Coefficients')
            xlabel(app.UIAxesCoeff, 'X')
            ylabel(app.UIAxesCoeff, 'Coeff')
            app.UIAxesReg = uiaxes(app.UIFigure);
            title(app.UIAxesReg, 'Regression on Test Data')
            xlabel(app.UIAxesReg, 'y_{pred}')
            ylabel(app.UIAxesReg, 'y_{true}')
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'PLS', 'CCA', 'PCR', 'RR', 'LASSO'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.MethodDropDown.Value = 'CCA';
            app.SNRLeftSliderLabel = uilabel(app.UIFigure);
            app.SNRLeftSliderLabel.Text = 'SNR Left';
            app.SNRLeftSlider = uislider(app.UIFigure);
            app.SNRLeftSlider.Limits = [1 50];
            app.SNRLeftSlider.MajorTicks = [1 10 20 30 40 50];
            app.SNRLeftSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.SNRLeftSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.SNRLeftSlider.Value = 30;
            app.TrainTestSplitSlider_3Label = uilabel(app.UIFigure);
            app.TrainTestSplitSlider_3Label.Text = 'Train-Test Split';
            app.TrainTestSplitSlider = uislider(app.UIFigure);
            app.TrainTestSplitSlider.Limits = [0.1 0.9];
            app.TrainTestSplitSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.TrainTestSplitSlider.Value = 0.7;
            app.DatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.DatapointsEditFieldLabel.Text = '#Datapoints';
            app.DatapointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatapointsEditField.Limits = [10 10000];
            app.DatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DatapointsEditField.Value = 50;
            app.relevantDatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.relevantDatapointsEditFieldLabel.Text = '#relevant Datapoints';
            app.relevantDatapointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.relevantDatapointsEditField.Limits = [2 5000];
            app.relevantDatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.relevantDatapointsEditField.Value = 10;
            app.ExperimentsEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentsEditFieldLabel.Text = '#Experiments';
            app.ExperimentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExperimentsEditField.Limits = [2 10000];
            app.ExperimentsEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.ExperimentsEditField.Value = 30;
            app.StartVEditFieldLabel = uilabel(app.UIFigure);
            app.StartVEditFieldLabel.Text = 'Start V';
            app.StartVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StartVEditField.Value = 2;
            app.EndVLabel = uilabel(app.UIFigure);
            app.EndVLabel.Text = 'End   V';
            app.EndVEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.EndVEditField.Value = -5;
            app.SignalSliderLabel = uilabel(app.UIFigure);
            app.SignalSliderLabel.Text = 'Signal';
            app.SignalSlider = uislider(app.UIFigure);
            app.SignalSlider.Limits = [1 50];
            app.SignalSlider.MajorTicks = [1 10 20 30 40 50];
            app.SignalSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.SignalSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.SignalSlider.Value = 30;
            app.RightSliderLabel = uilabel(app.UIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.UIFigure);
            app.RightSlider.Limits = [1 50];
            app.RightSlider.MajorTicks = [1 10 20 30 40 50];
            app.RightSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.RightSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.RightSlider.Value = 30;
            app.LeftVLabel = uilabel(app.UIFigure);
            app.LeftVLabel.Text = ' Left    V';
            app.LeftVEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.LeftVEditField.Value = -1;
            app.SigLEditFieldLabel = uilabel(app.UIFigure);
            app.SigLEditFieldLabel.Text = 'SigL';
            app.SigLEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigLEditField.Limits = [0 Inf];
            app.SigLEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigLEditField.Value = 2;
            app.RightVLabel = uilabel(app.UIFigure);
            app.RightVLabel.Text = 'Right  V';
            app.RightVEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightVEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.RightVEditField.Value = -4;
            app.SigREditFieldLabel = uilabel(app.UIFigure);
            app.SigREditFieldLabel.Text = 'SigR';
            app.SigREditField = uieditfield(app.UIFigure, 'numeric');
            app.SigREditField.Limits = [0 Inf];
            app.SigREditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigREditField.Value = 2;
            app.DrawNewSampleButton = uibutton(app.UIFigure, 'push');
            app.DrawNewSampleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.DrawNewSampleButton.Text = 'Draw New Sample';
            app.LatentVariableMethodDemonstratorLabel = uilabel(app.UIFigure);
            app.LatentVariableMethodDemonstratorLabel.Text = 'Latent Variable Method Demonstrator';
            app.PositionofSignalLabel = uilabel(app.UIFigure);
            app.PositionofSignalLabel.Text = 'Position of Signal';
            app.PositionSingnalSlider = uislider(app.UIFigure);
            app.PositionSingnalSlider.Limits = [0 1];
            app.PositionSingnalSlider.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.PositionSingnalSlider.Value = 0.5;
            app.NoiseCheckBox = uicheckbox(app.UIFigure);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SNRleftSliderValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.ComponentsEditFieldLabel = uilabel(app.UIFigure);
            app.ComponentsEditFieldLabel.Text = '#Components';
            app.ComponentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComponentsEditField.Limits = [1 10];
            app.ComponentsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.ComponentsEditField.Value = 1;
            app.RegularizationEditFieldLabel = uilabel(app.UIFigure);
            app.RegularizationEditFieldLabel.Text = 'Regularization';
            app.RegularizationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegularizationEditField.Limits = [0 Inf];
            app.RegularizationEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.RegularizationEditField.Value = 0.001;
            app.UpdatePlotsButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed2, true);
            app.UpdatePlotsButton.Text = {'Update Plots'; ''};
            app.UIAxesRegTrain = uiaxes(app.UIFigure);
            title(app.UIAxesRegTrain, 'Regression on Train Data')
            xlabel(app.UIAxesRegTrain, 'y_{pred}')
            ylabel(app.UIAxesRegTrain, 'y_{true}')
            app.UIAxesRegTrain.XTick = [0 0.5 1];
            app.SigSEditFieldLabel = uilabel(app.UIFigure);
            app.SigSEditFieldLabel.Text = 'SigS';
            app.SigSEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigSEditField.Limits = [0 Inf];
            app.SigSEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.SigEEditFieldLabel = uilabel(app.UIFigure);
            app.SigEEditFieldLabel.Text = 'SigE';
            app.SigEEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigEEditField.Limits = [0 Inf];
            app.SigEEditField.ValueChangedFcn = createCallbackFcn(app, @DrawNewSampleButtonPushed, true);
            app.StandardizeInputsCheckBox = uicheckbox(app.UIFigure);
            app.StandardizeInputsCheckBox.Text = {'Standardize Inputs'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lavade

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end